classdef PRE_PROC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        IOPanel              matlab.ui.container.Panel
        CompiledPath         matlab.ui.control.EditField
        OptionsPath          matlab.ui.control.EditField
        LoggingConsoleLabel  matlab.ui.control.Label
        BrowseCompiled       matlab.ui.control.Button
        BrowseOptions        matlab.ui.control.Button
        LoadCompiled         matlab.ui.control.Button
        LoadOptions          matlab.ui.control.Button
        HiRasLabel           matlab.ui.control.Label
        v069Label            matlab.ui.control.Label
        Image                matlab.ui.control.Image
        LogTextArea          matlab.ui.control.TextArea
        Suite2PPath          matlab.ui.control.EditField
        BrowseSuite2P        matlab.ui.control.Button
        LoadSuite2P          matlab.ui.control.Button
        FIssaPath            matlab.ui.control.EditField
        BrowseFissa          matlab.ui.control.Button
        LoadFissa            matlab.ui.control.Button
        NeuronalROIs         matlab.ui.control.UIAxes
        RemovedROIs          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
          log;
          log_line=1;
          file_loc_compiled;
          file_loc_options;
          file_loc_suite2p;
          file_loc_fissa;
          ImData=struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             pwd2 = fileparts(which('PRE_PROC'));
                 %pwd2 = pwd;
            
            if exist([pwd2 '\hideFun'], 'dir')
                addpath([pwd2 '\hideFun']);
            else
                f_DA_update_log(app,'ROBO RAFA: You need to move to PRE PROC directory and reopen GUI!!!');
            end    
                
            f_DA_startup(app);
        end

        % Button pushed function: BrowseCompiled
        function BrowseCompiledButtonPushed(app, event)
            f_DA_browse_results(app);
        end

        % Button pushed function: BrowseOptions
        function BrowseOptionsButtonPushed(app, event)
            f_DA_browse_IM(app);
        end

        % Button pushed function: LoadOptions
        function LoadOptionsButtonPushed(app, event)
            f_DA_load_IM(app);
        end

        % Button pushed function: LoadCompiled
        function LoadCompiledButtonPushed(app, event)
            f_DA_load_results(app);
            f_DA_initialize_ensemble(app);
            f_DA_initialize_decoding(app);
        end

        % Callback function
        function sLambdaPlotBGSelectionChanged(app, event)
            selectedButton = app.sLambdaPlotBG.SelectedObject;
            f_DA_structural_learning_plot(selectedButton,app);
        end

        % Callback function
        function SelectStimulusValueChanged(app, event)
            value = app.SelectStimulus.Value;
            f_DA_update_ensemble_values(app,value);
        end

        % Callback function
        function EnsemblePerformanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnsemblePerformanceButtonGroup.SelectedObject;
            f_DA_ensemble_performance(selectedButton,app);
        end

        % Callback function
        function SelectStimulus_DecodingValueChanged(app, event)
            value = app.SelectStimulus_Decoding.Value;
            f_DA_update_decoding_values(app,value);  
        end

        % Callback function
        function DecodingButtonGroupSelectionChanged(app, event)
            selectedButton = app.DecodingButtonGroup.SelectedObject;
            f_DA_update_decoding_performance(selectedButton,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IOPanel = uipanel(app.UIFigure);
            app.CompiledPath = uieditfield(app.IOPanel, 'text');
            app.CompiledPath.Value = 'Load Pre-Compiled File (.mat)';
            app.OptionsPath = uieditfield(app.IOPanel, 'text');
            app.OptionsPath.Value = 'Load Options File (.mat)';
            app.LoggingConsoleLabel = uilabel(app.IOPanel);
            app.LoggingConsoleLabel.Text = 'Logging Console';
            app.BrowseCompiled = uibutton(app.IOPanel, 'push');
            app.BrowseCompiled.ButtonPushedFcn = createCallbackFcn(app, @BrowseCompiledButtonPushed, true);
            app.BrowseCompiled.Text = 'Browse';
            app.BrowseOptions = uibutton(app.IOPanel, 'push');
            app.BrowseOptions.ButtonPushedFcn = createCallbackFcn(app, @BrowseOptionsButtonPushed, true);
            app.BrowseOptions.Text = 'Browse';
            app.LoadCompiled = uibutton(app.IOPanel, 'push');
            app.LoadCompiled.ButtonPushedFcn = createCallbackFcn(app, @LoadCompiledButtonPushed, true);
            app.LoadCompiled.Text = 'Load';
            app.LoadOptions = uibutton(app.IOPanel, 'push');
            app.LoadOptions.ButtonPushedFcn = createCallbackFcn(app, @LoadOptionsButtonPushed, true);
            app.LoadOptions.Text = 'Load';
            app.HiRasLabel = uilabel(app.IOPanel);
            app.HiRasLabel.Text = 'Hi Ras';
            app.v069Label = uilabel(app.IOPanel);
            app.v069Label.Text = 'v0.69';
            app.Image = uiimage(app.IOPanel);
            app.LogTextArea = uitextarea(app.IOPanel);
            app.LogTextArea.Editable = 'off';
            app.Suite2PPath = uieditfield(app.IOPanel, 'text');
            app.Suite2PPath.Value = 'Load Suite2P File (.mat)';
            app.BrowseSuite2P = uibutton(app.IOPanel, 'push');
            app.BrowseSuite2P.Text = 'Browse';
            app.LoadSuite2P = uibutton(app.IOPanel, 'push');
            app.LoadSuite2P.Text = 'Load';
            app.FIssaPath = uieditfield(app.IOPanel, 'text');
            app.FIssaPath.Value = 'Load Fissa File (.mat)';
            app.BrowseFissa = uibutton(app.IOPanel, 'push');
            app.BrowseFissa.Text = 'Browse';
            app.LoadFissa = uibutton(app.IOPanel, 'push');
            app.LoadFissa.Text = 'Load';
            app.NeuronalROIs = uiaxes(app.UIFigure);
            title(app.NeuronalROIs, 'Neuronal ROIs')
            app.RemovedROIs = uiaxes(app.UIFigure);
            title(app.RemovedROIs, 'Removed ROIs')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRE_PROC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end