classdef PRE_PROC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IOPanel                    matlab.ui.container.Panel
        CompiledPath               matlab.ui.control.EditField
        OptionsPath                matlab.ui.control.EditField
        LoggingConsoleLabel        matlab.ui.control.Label
        BrowseCompiled             matlab.ui.control.Button
        BrowseOptions              matlab.ui.control.Button
        LoadCompiled               matlab.ui.control.Button
        LoadOptions                matlab.ui.control.Button
        HiRasLabel                 matlab.ui.control.Label
        v069Label                  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        LogTextArea                matlab.ui.control.TextArea
        Suite2PPath                matlab.ui.control.EditField
        BrowseSuite2P              matlab.ui.control.Button
        ImportImaging              matlab.ui.control.Button
        FIssaPath                  matlab.ui.control.EditField
        BrowseFissa                matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        ROIsTab                    matlab.ui.container.Tab
        CalciumTracePlottingPanel  matlab.ui.container.Panel
        FissaLabel                 matlab.ui.control.Label
        PlotTrace_F_fissa          matlab.ui.control.CheckBox
        PlotTrace_Fneu_fissa       matlab.ui.control.CheckBox
        PlotTrace_cF_fissa         matlab.ui.control.CheckBox
        PlotTrace_AdcF_fissa       matlab.ui.control.CheckBox
        PlotTrace_sep_fissa        matlab.ui.control.CheckBox
        PlotTrace_nPil_fissa       matlab.ui.control.CheckBox
        PlotTrace_dFdT_fissa       matlab.ui.control.CheckBox
        PlotTrace_dFoF_fissa       matlab.ui.control.CheckBox
        PlotTrace_dFdT_fissa_4     matlab.ui.control.CheckBox
        PlotTrace_dFdT_fissa_5     matlab.ui.control.CheckBox
        Suite2PLabel               matlab.ui.control.Label
        PlotTrace_F_suite2p        matlab.ui.control.CheckBox
        PlotTrace_Fneu_suite2p     matlab.ui.control.CheckBox
        PlotTrace_cF_suite2p       matlab.ui.control.CheckBox
        PlotTrace_AdcF_suite2p     matlab.ui.control.CheckBox
        PlotTrace_dFdT_suite2p     matlab.ui.control.CheckBox
        PlotTrace_dFoF_suite2p     matlab.ui.control.CheckBox
        PlotTrace_dFdT_fissa_2     matlab.ui.control.CheckBox
        PlotTrace_dFdT_fissa_3     matlab.ui.control.CheckBox
        Panel                      matlab.ui.container.Panel
        UseYLimitsCheckBox         matlab.ui.control.CheckBox
        UseXLimitsCheckBox         matlab.ui.control.CheckBox
        XLimitUpper                matlab.ui.control.NumericEditField
        XLimitLower                matlab.ui.control.NumericEditField
        YLimitLower                matlab.ui.control.NumericEditField
        YLimitUpper                matlab.ui.control.NumericEditField
        NeuronalROIs               matlab.ui.control.UIAxes
        RemovedROIs                matlab.ui.control.UIAxes
        CellSelected               matlab.ui.control.UIAxes
        TracePlot                  matlab.ui.control.UIAxes
        Deconvolution              matlab.ui.container.Tab
        VideoMask                  matlab.ui.container.Tab
        PreprocessingTab           matlab.ui.container.Tab
        ExportTab                  matlab.ui.container.Tab
        ROISelectionPanel          matlab.ui.container.Panel
        ROILabel                   matlab.ui.control.Label
        SelectedCell               matlab.ui.control.Spinner
        TabGroup2                  matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
          log;
          log_line=1;
          file_loc_compiled;
          file_loc_options;
          file_loc_suite2p;
          file_loc_fissa;
          ImData=struct();
          plotTraceStyles=struct();
          limitTracePlot_X=0;
          limitTracePlot_Y=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             pwd2 = fileparts(which('PRE_PROC'));
                 %pwd2 = pwd;
            
            if exist([pwd2 '\hideFun'], 'dir')
                addpath([pwd2 '\hideFun']);
            else
                f_DA_update_log(app,'ROBO RAFA: You need to move to PRE PROC directory and reopen GUI!!!');
            end    
                
            f_DA_startup(app);
            f_DA_plotTraceStyles_constructor(app);
        end

        % Button pushed function: BrowseCompiled
        function BrowseCompiledButtonPushed(app, event)
            f_DA_browse_compiled(app);
        end

        % Button pushed function: BrowseOptions
        function BrowseOptionsButtonPushed(app, event)
            f_DA_browse_options(app);
        end

        % Button pushed function: BrowseFissa
        function BrowseFissaButtonPushed(app, event)
           f_DA_browse_fissa(app);
        end

        % Button pushed function: BrowseSuite2P
        function BrowseSuite2PButtonPushed(app, event)
            f_DA_browse_suite2p(app);
        end

        % Button pushed function: LoadCompiled
        function LoadCompiledButtonPushed(app, event)
            f_DA_load_compiled(app);
        end

        % Button pushed function: LoadOptions
        function LoadOptionsButtonPushed(app, event)
            f_DA_load_options(app);
        end

        % Button pushed function: ImportImaging
        function ImportImagingButtonPushed(app, event)
            f_DA_import_parse_imaging(app);
            f_DA_plotTraceStyles_constructor(app);
            f_DA_initialize_data(app);
        end

        % Value changed function: UseXLimitsCheckBox
        function UseXLimitsCheckBoxValueChanged(app, event)
            value = app.UseXLimitsCheckBox.Value;
            app.limitTracePlot_X=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_F_suite2p
        function PlotTrace_F_suite2pValueChanged(app, event)
            value = app.PlotTrace_F_suite2p.Value;
            app.plotTraceStyles.PlotF_suite2p  = value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_Fneu_suite2p
        function PlotTrace_Fneu_suite2pValueChanged(app, event)
            value = app.PlotTrace_Fneu_suite2p.Value;
            app.plotTraceStyles.PlotFNeu_suite2p=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_cF_suite2p
        function PlotTrace_cF_suite2pValueChanged(app, event)
            value = app.PlotTrace_cF_suite2p.Value;
            app.plotTraceStyles.PlotcF_suite2p=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_AdcF_suite2p
        function PlotTrace_AdcF_suite2pValueChanged(app, event)
            value = app.PlotTrace_AdcF_suite2p.Value;
            app.plotTraceStyles.PlotAdcF_suite2p=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_dFdT_suite2p
        function PlotTrace_dFdT_suite2pValueChanged(app, event)
            value = app.PlotTrace_dFdT_suite2p.Value;
            app.plotTraceStyles.PlotdFdT_suite2p=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_dFoF_suite2p
        function PlotTrace_dFoF_suite2pValueChanged(app, event)
            value = app.PlotTrace_dFoF_suite2p.Value;
            app.plotTraceStyles.PlotdFoF_suite2p=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: UseYLimitsCheckBox
        function UseYLimitsCheckBoxValueChanged(app, event)
            value = app.UseYLimitsCheckBox.Value;
            app.limitTracePlot_Y = value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: SelectedCell
        function SelectedCellValueChanged(app, event)
            value = app.SelectedCell.Value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_F_fissa
        function PlotTrace_F_fissaValueChanged(app, event)
            value = app.PlotTrace_F_fissa.Value;
            app.plotTraceStyles.PlotF_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_Fneu_fissa
        function PlotTrace_Fneu_fissaValueChanged(app, event)
            value = app.PlotTrace_Fneu_fissa.Value;
            app.plotTraceStyles.PlotFNeu_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_cF_fissa
        function PlotTrace_cF_fissaValueChanged(app, event)
            value = app.PlotTrace_cF_fissa.Value;
            app.plotTraceStyles.PlotcF_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_AdcF_fissa
        function PlotTrace_AdcF_fissaValueChanged(app, event)
            value = app.PlotTrace_AdcF_fissa.Value;
            app.plotTraceStyles.PlotAdcF_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_sep_fissa
        function PlotTrace_sep_fissaValueChanged(app, event)
            value = app.PlotTrace_sep_fissa.Value;
            app.plotTraceStyles.PlotSep_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_nPil_fissa
        function PlotTrace_nPil_fissaValueChanged(app, event)
            value = app.PlotTrace_nPil_fissa.Value;
            app.plotTraceStyles.PlotnPil_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_dFdT_fissa
        function PlotTrace_dFdT_fissaValueChanged(app, event)
            value = app.PlotTrace_dFdT_fissa.Value;
            app.plotTraceStyles.PlotdFdT_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: PlotTrace_dFoF_fissa
        function PlotTrace_dFoF_fissaValueChanged(app, event)
            value = app.PlotTrace_dFoF_fissa.Value;
            app.plotTraceStyles.dFoF_fissa=value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: XLimitLower
        function XLimitLowerValueChanged(app, event)
            value = app.XLimitLower.Value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: YLimitLower
        function YLimitLowerValueChanged(app, event)
            value = app.YLimitLower.Value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: XLimitUpper
        function XLimitUpperValueChanged(app, event)
            value = app.XLimitUpper.Value;
            f_DA_update_trace_plots(app);
        end

        % Value changed function: YLimitUpper
        function YLimitUpperValueChanged(app, event)
            value = app.YLimitUpper.Value;
            f_DA_update_trace_plots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IOPanel = uipanel(app.UIFigure);
            app.CompiledPath = uieditfield(app.IOPanel, 'text');
            app.CompiledPath.Value = 'Load Pre-Compiled File (.mat)';
            app.OptionsPath = uieditfield(app.IOPanel, 'text');
            app.OptionsPath.Value = 'Load Options File (.mat)';
            app.LoggingConsoleLabel = uilabel(app.IOPanel);
            app.LoggingConsoleLabel.Text = 'Logging Console';
            app.BrowseCompiled = uibutton(app.IOPanel, 'push');
            app.BrowseCompiled.ButtonPushedFcn = createCallbackFcn(app, @BrowseCompiledButtonPushed, true);
            app.BrowseCompiled.Text = 'Browse';
            app.BrowseOptions = uibutton(app.IOPanel, 'push');
            app.BrowseOptions.ButtonPushedFcn = createCallbackFcn(app, @BrowseOptionsButtonPushed, true);
            app.BrowseOptions.Text = 'Browse';
            app.LoadCompiled = uibutton(app.IOPanel, 'push');
            app.LoadCompiled.ButtonPushedFcn = createCallbackFcn(app, @LoadCompiledButtonPushed, true);
            app.LoadCompiled.Text = 'Load';
            app.LoadOptions = uibutton(app.IOPanel, 'push');
            app.LoadOptions.ButtonPushedFcn = createCallbackFcn(app, @LoadOptionsButtonPushed, true);
            app.LoadOptions.Text = 'Load';
            app.HiRasLabel = uilabel(app.IOPanel);
            app.HiRasLabel.Text = 'Hi Ras';
            app.v069Label = uilabel(app.IOPanel);
            app.v069Label.Text = 'v0.69';
            app.Image = uiimage(app.IOPanel);
            app.Image.ImageSource = 'DENMARK.jpg';
            app.LogTextArea = uitextarea(app.IOPanel);
            app.LogTextArea.Editable = 'off';
            app.Suite2PPath = uieditfield(app.IOPanel, 'text');
            app.Suite2PPath.Value = 'Suite2P File Path (.mat)';
            app.BrowseSuite2P = uibutton(app.IOPanel, 'push');
            app.BrowseSuite2P.ButtonPushedFcn = createCallbackFcn(app, @BrowseSuite2PButtonPushed, true);
            app.BrowseSuite2P.Text = 'Browse';
            app.ImportImaging = uibutton(app.IOPanel, 'push');
            app.ImportImaging.ButtonPushedFcn = createCallbackFcn(app, @ImportImagingButtonPushed, true);
            app.ImportImaging.Text = {'Import '; 'Imaging '; 'Files'};
            app.FIssaPath = uieditfield(app.IOPanel, 'text');
            app.FIssaPath.Value = 'Fissa File Path (.mat)';
            app.BrowseFissa = uibutton(app.IOPanel, 'push');
            app.BrowseFissa.ButtonPushedFcn = createCallbackFcn(app, @BrowseFissaButtonPushed, true);
            app.BrowseFissa.Text = 'Browse';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ROIsTab = uitab(app.TabGroup);
            app.ROIsTab.Title = 'ROIs';
            app.CalciumTracePlottingPanel = uipanel(app.ROIsTab);
            app.CalciumTracePlottingPanel.Title = 'Calcium Trace Plotting';
            app.FissaLabel = uilabel(app.CalciumTracePlottingPanel);
            app.FissaLabel.Text = 'Fissa';
            app.PlotTrace_F_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_F_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_F_fissaValueChanged, true);
            app.PlotTrace_F_fissa.Text = 'Plot F';
            app.PlotTrace_Fneu_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_Fneu_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_Fneu_fissaValueChanged, true);
            app.PlotTrace_Fneu_fissa.Text = 'Plot Fneu';
            app.PlotTrace_cF_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_cF_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_cF_fissaValueChanged, true);
            app.PlotTrace_cF_fissa.Text = 'Plot cF';
            app.PlotTrace_AdcF_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_AdcF_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_AdcF_fissaValueChanged, true);
            app.PlotTrace_AdcF_fissa.Text = 'Plot AdcF';
            app.PlotTrace_sep_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_sep_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_sep_fissaValueChanged, true);
            app.PlotTrace_sep_fissa.Text = 'Plot Unmatched';
            app.PlotTrace_nPil_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_nPil_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_nPil_fissaValueChanged, true);
            app.PlotTrace_nPil_fissa.Text = 'Plot nPil';
            app.PlotTrace_dFdT_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_dFdT_fissaValueChanged, true);
            app.PlotTrace_dFdT_fissa.Text = 'Plot dF/dT';
            app.PlotTrace_dFoF_fissa = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFoF_fissa.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_dFoF_fissaValueChanged, true);
            app.PlotTrace_dFoF_fissa.Text = 'Plot (F-Fo)/Fo';
            app.PlotTrace_dFdT_fissa_4 = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_fissa_4.Text = 'Plot Decon.';
            app.PlotTrace_dFdT_fissa_5 = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_fissa_5.Text = 'Plot Spikes';
            app.Suite2PLabel = uilabel(app.CalciumTracePlottingPanel);
            app.Suite2PLabel.Text = 'Suite2P';
            app.PlotTrace_F_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_F_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_F_suite2pValueChanged, true);
            app.PlotTrace_F_suite2p.Text = 'Plot F';
            app.PlotTrace_Fneu_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_Fneu_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_Fneu_suite2pValueChanged, true);
            app.PlotTrace_Fneu_suite2p.Text = 'Plot Fneu';
            app.PlotTrace_cF_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_cF_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_cF_suite2pValueChanged, true);
            app.PlotTrace_cF_suite2p.Text = 'Plot cF';
            app.PlotTrace_AdcF_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_AdcF_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_AdcF_suite2pValueChanged, true);
            app.PlotTrace_AdcF_suite2p.Text = 'Plot AdcF';
            app.PlotTrace_dFdT_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_dFdT_suite2pValueChanged, true);
            app.PlotTrace_dFdT_suite2p.Text = 'Plot dF/dT';
            app.PlotTrace_dFoF_suite2p = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFoF_suite2p.ValueChangedFcn = createCallbackFcn(app, @PlotTrace_dFoF_suite2pValueChanged, true);
            app.PlotTrace_dFoF_suite2p.Text = 'Plot (F-Fo)/Fo';
            app.PlotTrace_dFdT_fissa_2 = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_fissa_2.Text = 'Plot Decon.';
            app.PlotTrace_dFdT_fissa_3 = uicheckbox(app.CalciumTracePlottingPanel);
            app.PlotTrace_dFdT_fissa_3.Text = 'Plot Spikes';
            app.Panel = uipanel(app.CalciumTracePlottingPanel);
            app.UseYLimitsCheckBox = uicheckbox(app.Panel);
            app.UseYLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseYLimitsCheckBoxValueChanged, true);
            app.UseYLimitsCheckBox.Text = 'Use YLimits';
            app.UseXLimitsCheckBox = uicheckbox(app.Panel);
            app.UseXLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseXLimitsCheckBoxValueChanged, true);
            app.UseXLimitsCheckBox.Text = 'Use XLimits';
            app.XLimitUpper = uieditfield(app.Panel, 'numeric');
            app.XLimitUpper.Limits = [1 Inf];
            app.XLimitUpper.ValueChangedFcn = createCallbackFcn(app, @XLimitUpperValueChanged, true);
            app.XLimitUpper.Value = 1;
            app.XLimitLower = uieditfield(app.Panel, 'numeric');
            app.XLimitLower.Limits = [0 Inf];
            app.XLimitLower.ValueChangedFcn = createCallbackFcn(app, @XLimitLowerValueChanged, true);
            app.YLimitLower = uieditfield(app.Panel, 'numeric');
            app.YLimitLower.ValueChangedFcn = createCallbackFcn(app, @YLimitLowerValueChanged, true);
            app.YLimitUpper = uieditfield(app.Panel, 'numeric');
            app.YLimitUpper.ValueChangedFcn = createCallbackFcn(app, @YLimitUpperValueChanged, true);
            app.YLimitUpper.Value = 1;
            app.NeuronalROIs = uiaxes(app.ROIsTab);
            title(app.NeuronalROIs, 'Neuronal ROIs')
            app.NeuronalROIs.XTick = [];
            app.NeuronalROIs.YTick = [];
            app.RemovedROIs = uiaxes(app.ROIsTab);
            title(app.RemovedROIs, 'Removed ROIs')
            app.RemovedROIs.XTick = [];
            app.RemovedROIs.YTick = [];
            app.CellSelected = uiaxes(app.ROIsTab);
            title(app.CellSelected, 'Selected ROI')
            app.CellSelected.XTick = [];
            app.CellSelected.YTick = [];
            app.TracePlot = uiaxes(app.ROIsTab);
            xlabel(app.TracePlot, 'Time (s)')
            app.Deconvolution = uitab(app.TabGroup);
            app.Deconvolution.Title = 'Deconvolution';
            app.VideoMask = uitab(app.TabGroup);
            app.VideoMask.Title = 'Video Mask';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ROISelectionPanel = uipanel(app.UIFigure);
            app.ROISelectionPanel.Title = 'ROI Selection';
            app.ROILabel = uilabel(app.ROISelectionPanel);
            app.ROILabel.Text = 'ROI';
            app.SelectedCell = uispinner(app.ROISelectionPanel);
            app.SelectedCell.Limits = [1 Inf];
            app.SelectedCell.ValueChangedFcn = createCallbackFcn(app, @SelectedCellValueChanged, true);
            app.SelectedCell.Value = 1;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Close-Up')
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRE_PROC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end